classdef metodoBiseccion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        table                      matlab.ui.control.Table
        tabularButton              matlab.ui.control.Button
        txtB                       matlab.ui.control.NumericEditField
        bEditFieldLabel            matlab.ui.control.Label
        txtA                       matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        txtI                       matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        funcion                    matlab.ui.control.EditField
        FuncionEditFieldLabel      matlab.ui.control.Label
        BiseccionLabel             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: tabularButton
        function tabularButtonPushed(app, event)
            a=app.txtA.Value;
            b=app.txtB.Value;
            it=app.txtI.Value;
            f=app.funcion.Value;
            
            m=biseccion(f,a,b,it);
    
    
            for i=1:it
                app.table.Data(i,1)=m(i,1);
                app.table.Data(i,2)=m(i,2);
                app.table.Data(i,3)=m(i,3);
                app.table.Data(i,4)=m(i,4);
                app.table.Data(i,5)=m(i,5);
                app.table.Data(i,6)=m(i,6);
                app.table.Data(i,7)=round(m(i,7),0);
               
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BiseccionLabel = uilabel(app.UIFigure);
            app.BiseccionLabel.Text = 'Biseccion';
            app.FuncionEditFieldLabel = uilabel(app.UIFigure);
            app.FuncionEditFieldLabel.Text = 'Funcion:';
            app.funcion = uieditfield(app.UIFigure, 'text');
            app.IteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones:';
            app.txtI = uieditfield(app.UIFigure, 'numeric');
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a:';
            app.txtA = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b:';
            app.txtB = uieditfield(app.UIFigure, 'numeric');
            app.tabularButton = uibutton(app.UIFigure, 'push');
            app.tabularButton.ButtonPushedFcn = createCallbackFcn(app, @tabularButtonPushed, true);
            app.tabularButton.Text = 'tabular';
            app.table = uitable(app.UIFigure);
            app.table.ColumnName = {'A'; 'F(A)'; 'B'; 'F(B)'; 'Mi'; 'F(Mi)'; 'Error'};
            app.table.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metodoBiseccion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end