classdef metodoReglaFalsa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabularButton              matlab.ui.control.Button
        X2                         matlab.ui.control.NumericEditField
        X2inicialEditFieldLabel    matlab.ui.control.Label
        X1                         matlab.ui.control.NumericEditField
        X1inicialEditFieldLabel    matlab.ui.control.Label
        Iteracione                 matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        table                      matlab.ui.control.Table
        funcion                    matlab.ui.control.EditField
        FuncionEditFieldLabel      matlab.ui.control.Label
        MetododelareglafalsaLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TabularButton
        function TabularButtonPushed(app, event)
            a=app.X1.Value;
            b=app.X2.Value;
            max=app.Iteracione.Value;
            f=app.funcion.Value;
            m=reglaFalsa(f,a,b,max);
            for i=1:max
                app.table.Data(i,1)=m(i,1);
                app.table.Data(i,2)=m(i,2);
                app.table.Data(i,3)=m(i,3);
                app.table.Data(i,4)=m(i,4);
                app.table.Data(i,5)=m(i,5);
                app.table.Data(i,6)=m(i,6);
                app.table.Data(i,7)=m(i,7);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MetododelareglafalsaLabel = uilabel(app.UIFigure);
            app.MetododelareglafalsaLabel.Text = 'Metodo de la regla falsa';
            app.FuncionEditFieldLabel = uilabel(app.UIFigure);
            app.FuncionEditFieldLabel.Text = 'Funcion';
            app.funcion = uieditfield(app.UIFigure, 'text');
            app.table = uitable(app.UIFigure);
            app.table.ColumnName = {'x1'; 'f(x1)'; 'x2'; 'f(x2)'; 'xi'; 'f(xi)'; 'error'};
            app.table.RowName = {};
            app.IteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones';
            app.Iteracione = uieditfield(app.UIFigure, 'numeric');
            app.X1inicialEditFieldLabel = uilabel(app.UIFigure);
            app.X1inicialEditFieldLabel.Text = 'X1 inicial';
            app.X1 = uieditfield(app.UIFigure, 'numeric');
            app.X2inicialEditFieldLabel = uilabel(app.UIFigure);
            app.X2inicialEditFieldLabel.Text = 'X2 inicial';
            app.X2 = uieditfield(app.UIFigure, 'numeric');
            app.TabularButton = uibutton(app.UIFigure, 'push');
            app.TabularButton.ButtonPushedFcn = createCallbackFcn(app, @TabularButtonPushed, true);
            app.TabularButton.Text = 'Tabular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metodoReglaFalsa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end