classdef metodoTangente < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        table                      matlab.ui.control.Table
        TABULARButton              matlab.ui.control.Button
        x2                         matlab.ui.control.NumericEditField
        X2inicialEditFieldLabel    matlab.ui.control.Label
        x1                         matlab.ui.control.NumericEditField
        X1inicialEditFieldLabel    matlab.ui.control.Label
        it                         matlab.ui.control.NumericEditField
        IteracionesEditFieldLabel  matlab.ui.control.Label
        funcion                    matlab.ui.control.EditField
        FuncionEditFieldLabel      matlab.ui.control.Label
        METODODELATANGENTELabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TABULARButton
        function TABULARButtonPushed(app, event)
            f=app.funcion.Value;
            a=app.x1.Value;
            b=app.x2.Value;
            max=app.it.Value;
            m=tangente(f,a,b,max);
            for i=1:max
                app.table.Data(i,1)=m(i,1);
                app.table.Data(i,2)=m(i,2);
                app.table.Data(i,3)=m(i,3);
                app.table.Data(i,4)=m(i,4);
                app.table.Data(i,5)=m(i,5);
                app.table.Data(i,6)=m(i,6);
                app.table.Data(i,7)=m(i,7);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.METODODELATANGENTELabel = uilabel(app.UIFigure);
            app.METODODELATANGENTELabel.Text = 'METODO DE LA TANGENTE';
            app.FuncionEditFieldLabel = uilabel(app.UIFigure);
            app.FuncionEditFieldLabel.Text = 'Funcion:';
            app.funcion = uieditfield(app.UIFigure, 'text');
            app.IteracionesEditFieldLabel = uilabel(app.UIFigure);
            app.IteracionesEditFieldLabel.Text = 'Iteraciones:';
            app.it = uieditfield(app.UIFigure, 'numeric');
            app.X1inicialEditFieldLabel = uilabel(app.UIFigure);
            app.X1inicialEditFieldLabel.Text = 'X1 inicial:';
            app.x1 = uieditfield(app.UIFigure, 'numeric');
            app.X2inicialEditFieldLabel = uilabel(app.UIFigure);
            app.X2inicialEditFieldLabel.Text = 'X2 inicial:';
            app.x2 = uieditfield(app.UIFigure, 'numeric');
            app.TABULARButton = uibutton(app.UIFigure, 'push');
            app.TABULARButton.ButtonPushedFcn = createCallbackFcn(app, @TABULARButtonPushed, true);
            app.TABULARButton.Text = 'TABULAR';
            app.table = uitable(app.UIFigure);
            app.table.ColumnName = {'X1'; 'F(X1)'; 'X2'; 'F(X2)'; 'Mi'; 'F(Mi)'; 'Error'};
            app.table.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metodoTangente

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end